classdef mainOrientationGUI_A002 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TestTriggerPanel             matlab.ui.container.Panel
        TestTriggerButton            matlab.ui.control.Button
        TriggerLamp                  matlab.ui.control.Lamp
        TriggerLampLabel             matlab.ui.control.Label
        SessionNameEditField         matlab.ui.control.EditField
        SessionNameEditFieldLabel    matlab.ui.control.Label
        TestStimulusPanel            matlab.ui.container.Panel
        ProvideStimulusButton        matlab.ui.control.Button
        OrientationDropDown          matlab.ui.control.DropDown
        OrientationDropDownLabel     matlab.ui.control.Label
        DigitDropDown                matlab.ui.control.DropDown
        DigitDropDownLabel           matlab.ui.control.Label
        ALYSSA5744409825MANNY7182191692Label  matlab.ui.control.Label
        GenerateTrialsButton         matlab.ui.control.Button
        DAQLamp                      matlab.ui.control.Lamp
        DAQLampLabel                 matlab.ui.control.Label
        ConnecttoDAQButton           matlab.ui.control.Button
        TrialInformationPanel        matlab.ui.container.Panel
        Protocol1Label               matlab.ui.control.Label
        Protocol2Label               matlab.ui.control.Label
        ShapeEditField               matlab.ui.control.EditField
        ShapeEditFieldLabel          matlab.ui.control.Label
        CurrentOrientationsPanel     matlab.ui.container.Panel
        D5EditField                  matlab.ui.control.NumericEditField
        D5EditFieldLabel             matlab.ui.control.Label
        D4EditField                  matlab.ui.control.NumericEditField
        D4EditFieldLabel             matlab.ui.control.Label
        D3EditField                  matlab.ui.control.NumericEditField
        D3EditFieldLabel             matlab.ui.control.Label
        D2EditField                  matlab.ui.control.NumericEditField
        D2EditFieldLabel             matlab.ui.control.Label
        DigitEditField               matlab.ui.control.NumericEditField
        DigitEditFieldLabel          matlab.ui.control.Label
        OrientationEditField         matlab.ui.control.NumericEditField
        OrientationEditFieldLabel    matlab.ui.control.Label
        TrialEditField               matlab.ui.control.NumericEditField
        TrialLabel                   matlab.ui.control.Label
        SessionControlsPanel         matlab.ui.container.Panel
        ExpStatusLamp                matlab.ui.control.Lamp
        ExpStatusLampLabel           matlab.ui.control.Label
        PAUSEButton                  matlab.ui.control.Button
        STOPButton                   matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        MotorCalibrationButton       matlab.ui.control.Button
        SessionNotesTextArea         matlab.ui.control.TextArea
        SessionNotesTextAreaLabel    matlab.ui.control.Label
        ProtocolSelectionandRecordingInfoPanel  matlab.ui.container.Panel
        SelectHandPositionDropDown   matlab.ui.control.DropDown
        SelectHandPositionDropDownLabel  matlab.ui.control.Label
        MonkeyNameDropDown           matlab.ui.control.DropDown
        MonkeyNameDropDownLabel      matlab.ui.control.Label
        SelectProtocolDropDown       matlab.ui.control.DropDown
        SelectProtocolDropDownLabel  matlab.ui.control.Label
        DeviceConnectionsPanel       matlab.ui.container.Panel
        ConnecttoMegaButton          matlab.ui.control.Button
        ConnecttoLeoButton           matlab.ui.control.Button
        MegaLamp                     matlab.ui.control.Lamp
        MegaLampLabel                matlab.ui.control.Label
        LeoLamp                      matlab.ui.control.Lamp
        LeoLampLabel                 matlab.ui.control.Label
        AnesthetizedMonkeyExperimentOrientationLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        gc MonkeyA002_Orientation.UtilityGUI.GUICore % core properties of GUI
        motorCalibApp % motor calibration app 

        % arduino serial connection to motors
        serialSM % serial connection to stepper motors
        serialLA % serial connection to linear actuators 

        % nidaq properties 
        signalManager % daq to send pulses to neuropixel recording  
        currentOutput
        nullOutputScan = zeros(1,2);
        analogListener % analog channel for listening for pulses sent to neuropixel machine
        pulseTimes % array of times relative to start of experiment for pulse times 

        % session parameters
        startTime
        endTime
        expstatus
        currentTrialNum 
        sessionDataFolder
        sessionDataFolderBox
        sessionFilename
        currentFilename
        currentFilenameBox
        startTrial
        endTrial 
        numSessionFiles 

        % tracking orientation of digit motors
        digitOri
        
    end
    
    methods (Access = private)
        
        function establishDAQConnection(app)
            app.signalManager = daq("ni");
            % setup daq channel based on connection properties
            addoutput(app.signalManager, "Dev1", 'Port0/Line0', 'Digital');
            addoutput(app.signalManager, "Dev1", 'Port0/Line1', 'Digital');
            app.currentOutput = app.nullOutputScan;
            write(app.signalManager, app.currentOutput);
        end
        
        function sendTrigger(app)
            app.currentOutput = ones(1,2); 
            initTrigger = GetSecs; 
            write(app.signalManager, app.currentOutput);
            while (GetSecs - initTrigger < 0.01)
                continue;  
            end
            app.currentOutput = app.nullOutputScan;
            write(app.signalManager, app.currentOutput);
        end
        
        function delayPeriod(app, delayDur)
            initDelay = GetSecs;
            while(GetSecs - initDelay < delayDur)
                drawnow(); 
            end
        end

        function sendMtrCommand(app, commandLabel, mtrNum, moveAmount)  
            % moveAmount = depth (in microseconds) for linear actuators or angle (deg) for stepper motors 

            switch commandLabel
                case 1 % move one linear actuator by specific depth 
                    serialCommand = sprintf("%d\n", [commandLabel mtrNum moveAmount]);
                    write(app.serialLA, serialCommand, "string"); 

                case 2 % provide stimulus indentation by moving linear actuators down
                    % e.g. app.sendMtrCommand(2, mtrNum, nan); 
                    % mtrNum = array of 4 binary numbers - 1 = move mtr, 0 = don't move mtr
                        % e.g. [1 1 1 1] moves all 4 motors 
                    serialCommand = sprintf("%d\n", [commandLabel mtrNum]); 
                    write(app.serialLA, serialCommand, "string"); 

                case 3 % move linear actuators back up (same as command 2 but just to move back up)
                    serialCommand = sprintf("%d\n", [commandLabel mtrNum]); 
                    write(app.serialLA, serialCommand, "string"); 

                case 6 % rotate given stepper motor by given angle
                    if strcmpi(app.SelectHandPositionDropDown.Value, "PalmUp")  
                        if moveAmount >= 0
                            direction = 1; % move CCW 
                        else 
                            direction = 0; % move CW
                        end
                    else
                        if moveAmount >= 0
                            direction = 0; % move CW 
                        else 
                            direction = 1; % move CCW
                        end
                    end
                    serialCommand = sprintf("%d\n%d\n%d\n%.2f\n", [commandLabel mtrNum direction abs(moveAmount)]); 
                    write(app.serialSM, serialCommand, "string");
            end
        end

        function moveAngle = calcMoveAngle(app, mtrNum, angle)
            currentAngle = app.digitOri{mtrNum}.Value;
            moveAngle = angle - currentAngle;
            app.digitOri{mtrNum}.Value = angle; 
        end

        function createSessionFile(app)
            trials = app.gc.econ.trials(app.startTrial:app.endTrial,:);
            triggerTimeStamps = app.pulseTimes; 
            save(app.currentFilename, "trials", "triggerTimeStamps"); 
            save(app.currentFilenameBox, "trials", "triggerTimeStamps"); 
        end

        function updateFilename(app)
            app.numSessionFiles = app.numSessionFiles + 1; 
            app.currentFilename = fullfile(app.sessionDataFolder, strcat(app.sessionFilename, '_File', num2str(app.numSessionFiles),'.mat')); 
            app.currentFilenameBox = fullfile(app.sessionDataFolderBox, strcat(app.sessionFilename, '_File', num2str(app.numSessionFiles),'.mat')); 
            app.SessionNameEditField.Value = strcat(app.sessionFilename, '_File', num2str(app.numSessionFiles));
        end

        function updateData(app)
            app.gc.econ.expinfo.notes = app.SessionNotesTextArea.Value; 
            expinfo = app.gc.econ.expinfo;
            trials = app.gc.econ.trials;
            save(app.gc.econ.dataLocal,"expinfo", "trials", '-append');
            save(app.gc.econ.dataBox,"expinfo", "trials", '-append');
        end

        function saveData(app)
            folderLocal = app.gc.econ.localfolder; 
            folderBox = app.gc.econ.boxfolder; 
            dateTimeNow = datetime('now', 'Format', 'yy_MM_d_HH_mm', 'TimeZone', 'America/New_York');
            dateTimeNowString = string(dateTimeNow);
            dataFoldername = strcat([app.MonkeyNameDropDown.Value '_ori' app.SelectProtocolDropDown.Value '_'], dateTimeNowString);
            app.SessionNameEditField.Value = dataFoldername; 
            dataLocalFolder = mkdir(folderLocal, dataFoldername);
            dataBoxFolder = mkdir(folderBox, dataFoldername);
            dataLocalFoldername = fullfile(folderLocal, dataFoldername);
            dataBoxFoldername = fullfile(folderBox, dataFoldername); 
            
            filename = strcat(dataFoldername, '.mat');
            expinfo.sessionName = dataFoldername; 
            expinfo.subject = app.MonkeyNameDropDown.Value; 
            expinfo.protocol = app.SelectProtocolDropDown.Value; 
            expinfo.HandPosition = app.SelectHandPositionDropDown.Value;
            expinfo.sessionStartTime = dateTimeNowString;
            expinfo.notes = app.SessionNotesTextArea.Value; 
            expinfo.pausedTrials = [];
            expinfo.stoppedTrial = []; 
            app.gc.econ.expinfo = expinfo; 
            trials = app.gc.econ.trials;
            
            filenameNWB = strcat(dataFoldername, '.nwb');
            nwb = NwbFile('session_description', app.SessionNotesTextArea.Value, ...
                'identifier', convertStringsToChars(dataFoldername), ...
                'session_start_time', dateTimeNow, ...
                'general_session_id', convertStringsToChars(dataFoldername));
            nwbLocLocal = fullfile(dataLocalFoldername,filenameNWB); 
            nwbLocBox = fullfile(dataBoxFoldername, filenameNWB); 
            
            save(fullfile(dataLocalFoldername,filename), "expinfo", "trials"); 
            save(fullfile(dataBoxFoldername,filename), "expinfo", "trials"); 
            nwbExport(nwb, nwbLocLocal);
            nwbExport(nwb, nwbLocBox);
            
            app.gc.econ.dataLocal = fullfile(dataLocalFoldername,filename); 
            app.gc.econ.dataBox = fullfile(dataBoxFoldername,filename); 
            disp("Data Saved");

            app.sessionDataFolder = dataLocalFoldername; 
            app.sessionDataFolderBox = dataBoxFoldername; 
            app.sessionFilename = dataFoldername; 
        end

        function backgroundListener(app, src, ~)
            [data, ~, ~] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            % record time of a pulse 
            if any(diff(data(:, 1)) > 2) % if see an increase in voltage by more than 2V
                timeOfPulse = GetSecs; 
                app.TriggerLamp.Color = app.gc.color.Green;
                timeOfPulseRelativeToSessionStart = timeOfPulse - app.startTime;
                app.pulseTimes = [app.pulseTimes; timeOfPulseRelativeToSessionStart];
            else
                app.TriggerLamp.Color = app.gc.color.Gray;
            end
            
            drawnow();
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConnecttoDAQButton.Visible = false;
            app.ConnecttoMegaButton.Visible = false;
            app.ConnecttoLeoButton.Visible = false;

            app.gc = MonkeyA002_Orientation.UtilityGUI.GUICore(); % set up GUI core

            app.digitOri = {app.D2EditField, app.D3EditField, app.D4EditField, app.D5EditField}; % setup digit orientation tracking 
            for d = 1:length(app.digitOri)
                app.digitOri{d}.Value = 0;
            end

            % connect to NIDAQ
            try
                app.establishDAQConnection(); 
                app.DAQLamp.Color = app.gc.color.Green;
            catch
                app.DAQLamp.Color = app.gc.color.Red;
                app.ConnecttoDAQButton.Visible = true; 
            end

            % create background analog listener for pulses 
            app.analogListener = daq("ni");
            if isempty(app.analogListener)
                error("No NIDAQ devices were detected");
            end
            app.analogListener.Rate = app.gc.ccon.DAQSamplingRate; % scanning rate for sensor reading 
            app.analogListener.addinput("Dev1", ['ai' num2str(app.gc.ccon.DAQ_AIChannel)], "Voltage"); % analog listener
            app.analogListener.ScansAvailableFcnCount = app.gc.ccon.DAQscansAvailableFcnCount;
            app.analogListener.ScansAvailableFcn = @ (src, event) app.backgroundListener(src, event);
            start(app.analogListener, "Continuous");
            disp('Connected to Analog Listener'); 

            % connect to arduino mega serial port for stepper motors
            try
                app.serialSM = serialport(app.gc.ccon.megaPort, app.gc.ccon.megaBaudRate);
                configureTerminator(app.serialSM, "CR/LF");
                set(app.serialSM, 'Timeout',15); 
                app.gc.ccon.mega = app.serialSM; 
                app.MegaLamp.Color = app.gc.color.Green;
            catch
                app.MegaLamp.Color = app.gc.color.Red;
                app.ConnecttoMegaButton.Visible = true; 
            end

            % connect to arduino leo serial port for linear actuators
            try
                app.serialLA = serialport(app.gc.ccon.leoPort, app.gc.ccon.leoBaudRate);
                configureTerminator(app.serialLA, "CR/LF");
                set(app.serialLA, 'Timeout',15); 
                app.gc.ccon.leo = app.serialLA; 
                app.LeoLamp.Color = app.gc.color.Green;
            catch
                app.LeoLamp.Color = app.gc.color.Red;
                app.ConnecttoLeoButton.Visible = true; 
            end

            app.expstatus = -1; % initialize experiment status 
            app.currentTrialNum = 0; % initialize current trial num 
        end

        % Button pushed function: ConnecttoDAQButton
        function ConnecttoDAQButtonPushed(app, event)
            try
                app.establishDAQConnection(); 
                app.DAQLamp.Color = app.gc.color.Green;
            catch
                app.DAQLamp.Color = app.gc.color.Red;
            end
        end

        % Button pushed function: ConnecttoMegaButton
        function ConnecttoMegaButtonPushed(app, event)
            try
                app.serialSM = serialport(app.gc.ccon.megaPort, app.gc.ccon.megaBaudRate);
                configureTerminator(app.serialSM, "CR/LF");
                set(app.serialSM, 'Timeout',15); 
                app.gc.ccon.mega = app.serialSM; 
                app.MegaLamp.Color = app.gc.color.Green;
            catch
                app.MegaLamp.Color = app.gc.color.Red;
            end
        end

        % Button pushed function: ConnecttoLeoButton
        function ConnecttoLeoButtonPushed(app, event)
            try
                app.serialLA = serialport(app.gc.ccon.leoPort, app.gc.ccon.leoBaudRate);
                configureTerminator(app.serialLA, "CR/LF");
                set(app.serialLA, 'Timeout',15); 
                app.gc.ccon.leo = app.serialLA; 
                app.LeoLamp.Color = app.gc.color.Green;
            catch
                app.LeoLamp.Color = app.gc.color.Red;
            end
        end

        % Button pushed function: GenerateTrialsButton
        function GenerateTrialsButtonPushed(app, event)
            app.ExpStatusLamp.Color = app.gc.color.Blue; 
            handPosition = app.SelectHandPositionDropDown.Value;
            if strcmpi(app.SelectProtocolDropDown.Value, "P1") 
                app.gc.econ.trials = MonkeyA002_Orientation.Scripts.generateTrials_P1(handPosition);
            elseif strcmpi(app.SelectProtocolDropDown.Value, "P2")
                app.gc.econ.trials = MonkeyA002_Orientation.Scripts.generateTrials_P2(handPosition);
            end

            app.gc.econ.numTrials = height(app.gc.econ.trials); 
            app.currentTrialNum = 0; 
            app.pulseTimes = [];
            app.numSessionFiles = 0;

            app.saveData(); 
            app.updateFilename(); 
            
        end

        % Button pushed function: MotorCalibrationButton
        function MotorCalibrationButtonPushed(app, event)
            app.motorCalibApp = MonkeyA002_Orientation.GUI.motorCalibration_V2(app);

        end

        % Button pushed function: ProvideStimulusButton
        function ProvideStimulusButtonPushed(app, event)
            mtrNum = str2double(app.DigitDropDown.Value)-1;
            angle = str2double(app.OrientationDropDown.Value); 
            moveAngle = app.calcMoveAngle(mtrNum, angle); 

            app.sendMtrCommand(6, mtrNum, moveAngle); % rotate stepper motor

            app.delayPeriod(1.75); 
            
            mtrMove = zeros(1,4);
            mtrMove(mtrNum) = 1; 
            app.sendMtrCommand(2, mtrMove, nan); % indent linear actuator 

            app.delayPeriod(1);

            app.sendMtrCommand(3, mtrMove, nan); % move linear actuator back up 

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            fig = uifigure;
            msg = 'Has the Neuropixel recording started?'; 
            selection = uiconfirm(fig,msg,'Confirm Recording','Icon','warning');
            switch selection
                case 'OK'
                    delete(fig); 
                case 'Cancel'
                    delete(fig);
                    return;
            end
            
            % initialize trial number index 
            switch app.expstatus
                case -1 % not started or stopped 
                    i = 0; 
                case 0 % paused or next block 
                    i = app.currentTrialNum; 
            end
            app.startTrial = i + 1; % first trial of a session block file 

            % restart pulseTimes
            app.pulseTimes = []; 
            
            app.ExpStatusLamp.Color = app.gc.color.Green;
            app.expstatus = 1;
            disp("Starting experiment..."); 
            app.startTime = GetSecs; 

            % Send pulse to DAQ to start neuropixel recording 
            app.sendTrigger(); 
            
            % RUN EXPERIMENT TRIALS 
            while i < app.gc.econ.numTrials
                i = i + 1; % move to next trial 
                app.currentTrialNum = i; 

                if app.expstatus == -1 % if stopped experiment 
                    app.endTrial = i-1; 
                    app.gc.econ.expinfo.stoppedTrial = [app.gc.econ.expinfo.stoppedTrial, i-1]; 
                    app.currentTrialNum = 0;
                    app.createSessionFile(); 
                    app.updateData(); 
                    break;
                elseif app.expstatus == 0 % if paused experiment
                    app.endTrial = i-1; 
                    app.currentTrialNum = i-1;
                    app.gc.econ.expinfo.pausedTrials = [app.gc.econ.expinfo.pausedTrials, i-1]; 
                    app.createSessionFile(); 
                    app.updateFilename(); % prep for next file recording
                    app.updateData();
                    break;
                end

                % update trial information 
                app.TrialEditField.Value = i;
                if strcmpi(app.SelectProtocolDropDown.Value, "P1") 
                    app.DigitEditField.Value = app.gc.econ.trials.digit(i);
                    app.OrientationEditField.Value = app.gc.econ.trials.orientation(i);
                else
                    app.ShapeEditField.Value = app.gc.econ.trials.shape(i);
                end

                % rotate stepper motor(s) 
                angles = app.gc.econ.trials.orientation(i);
                if iscell(angles)
                    angles = cell2mat(angles);
                end

                for m = 1:length(angles)
                    if length(angles) == 1
                        mtrNum = app.DigitEditField.Value - 1;
                    else
                        mtrNum = m;
                    end
                    moveAngle = app.calcMoveAngle(mtrNum, angles(m));
                    if moveAngle > 0 || moveAngle < 0 
                        app.sendMtrCommand(6, mtrNum, moveAngle);
                        if m ~= length(angles)
                            app.delayPeriod(1.75);
                        end
                    end
                end

                app.delayPeriod(1.75); 

                % send DAQ pulse to neuropixel machine and analog listener
                app.currentOutput = ones(1,2);
                startTimeForCode = GetSecs;
                write(app.signalManager, app.currentOutput);

                % store time relative to start of experiment
                app.gc.econ.trials.TriggerTimeRelativetoSessionStart(i) = startTimeForCode - app.startTime;

                % send motor command to provide stimulus
                if strcmpi(app.SelectProtocolDropDown.Value, "P1") 
                    mtrMove = zeros(1,4);
                    mtrMove(app.DigitEditField.Value-1) = 1;
                else
                    mtrMove = ones(1,4);
                end
                app.sendMtrCommand(2,mtrMove,nan); % motor command to ident stimulus
                startTimeOfTouchCommand = GetSecs;
                app.gc.econ.trials.TriggerTimeOfTouchCommandRelativeToElectrodeSignal(i) = startTimeOfTouchCommand - startTimeForCode;
                OffSignal = 0;
                
                % send a signal to the signal manager, and then after 0.1 sec
                % send the null output scan.
                while((GetSecs - startTimeOfTouchCommand)*1000 <= 1000) % stimDur = 1000 ms
                    if ((GetSecs - startTimeForCode) < 0.01)
                        continue;
                    elseif ~OffSignal
                        app.currentOutput = app.nullOutputScan;
                        write(app.signalManager, app.currentOutput);
                        OffSignal = 1;
                    end
                end
                
                app.sendMtrCommand(3,mtrMove,nan); % motor command to move stimulus back up 

                app.currentOutput = app.nullOutputScan;
                write(app.signalManager, app.currentOutput);

                % ITI 
                ITI = round((0.5 + (1.5-0.5).*rand(1,1)),2);
                app.gc.econ.trials.ITI(i) = ITI;
                app.delayPeriod(ITI);

                app.updateData(); 
                
                % check if moving on to next block of trials 
                if i < app.gc.econ.numTrials && app.gc.econ.trials.block(i) ~= app.gc.econ.trials.block(i+1) 
                    app.endTrial = i;
                    app.createSessionFile();
                    app.updateFilename(); % prep for next file recording
                    app.PAUSEButtonPushed();
                    break; 
                end

            end

            if i == app.gc.econ.numTrials
                app.endTrial = i;
                app.createSessionFile();
                disp('End of Experiment'); 
            end

        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.expstatus = 0;
            app.ExpStatusLamp.Color = app.gc.color.Yellow; 
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.expstatus = -1;
            app.ExpStatusLamp.Color = app.gc.color.Red; 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: TestTriggerButton
        function TestTriggerButtonPushed(app, event)
            app.sendTrigger(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AnesthetizedMonkeyExperimentOrientationLabel = uilabel(app.UIFigure);
            app.AnesthetizedMonkeyExperimentOrientationLabel.Text = 'Anesthetized Monkey Experiment - Orientation';
            app.DeviceConnectionsPanel = uipanel(app.UIFigure);
            app.DeviceConnectionsPanel.Title = '1. Device Connections';
            app.LeoLampLabel = uilabel(app.DeviceConnectionsPanel);
            app.LeoLampLabel.Text = 'Leo';
            app.LeoLamp = uilamp(app.DeviceConnectionsPanel);
            app.MegaLampLabel = uilabel(app.DeviceConnectionsPanel);
            app.MegaLampLabel.Text = 'Mega';
            app.MegaLamp = uilamp(app.DeviceConnectionsPanel);
            app.ConnecttoLeoButton = uibutton(app.DeviceConnectionsPanel, 'push');
            app.ConnecttoLeoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoLeoButtonPushed, true);
            app.ConnecttoLeoButton.Text = 'Connect to Leo';
            app.ConnecttoMegaButton = uibutton(app.DeviceConnectionsPanel, 'push');
            app.ConnecttoMegaButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoMegaButtonPushed, true);
            app.ConnecttoMegaButton.Text = 'Connect to Mega';
            app.ProtocolSelectionandRecordingInfoPanel = uipanel(app.UIFigure);
            app.ProtocolSelectionandRecordingInfoPanel.Title = '2. Protocol Selection and Recording Info';
            app.SelectProtocolDropDownLabel = uilabel(app.ProtocolSelectionandRecordingInfoPanel);
            app.SelectProtocolDropDownLabel.Text = 'Select Protocol';
            app.SelectProtocolDropDown = uidropdown(app.ProtocolSelectionandRecordingInfoPanel);
            app.SelectProtocolDropDown.Items = {'P1', 'P2'};
            app.SelectProtocolDropDown.Value = 'P1';
            app.MonkeyNameDropDownLabel = uilabel(app.ProtocolSelectionandRecordingInfoPanel);
            app.MonkeyNameDropDownLabel.Text = 'Monkey Name';
            app.MonkeyNameDropDown = uidropdown(app.ProtocolSelectionandRecordingInfoPanel);
            app.MonkeyNameDropDown.Items = {'M2412', 'Abbott'};
            app.MonkeyNameDropDown.Value = 'M2412';
            app.SelectHandPositionDropDownLabel = uilabel(app.ProtocolSelectionandRecordingInfoPanel);
            app.SelectHandPositionDropDownLabel.Text = 'Select Hand Position';
            app.SelectHandPositionDropDown = uidropdown(app.ProtocolSelectionandRecordingInfoPanel);
            app.SelectHandPositionDropDown.Items = {'PalmUp', 'PalmDown'};
            app.SelectHandPositionDropDown.Value = 'PalmUp';
            app.SessionNotesTextAreaLabel = uilabel(app.UIFigure);
            app.SessionNotesTextAreaLabel.Text = 'Session Notes';
            app.SessionNotesTextArea = uitextarea(app.UIFigure);
            app.MotorCalibrationButton = uibutton(app.UIFigure, 'push');
            app.MotorCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @MotorCalibrationButtonPushed, true);
            app.MotorCalibrationButton.Text = '3. Motor Calibration';
            app.SessionControlsPanel = uipanel(app.UIFigure);
            app.SessionControlsPanel.Title = '5. Session Controls';
            app.STARTButton = uibutton(app.SessionControlsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.SessionControlsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.PAUSEButton = uibutton(app.SessionControlsPanel, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.ExpStatusLampLabel = uilabel(app.SessionControlsPanel);
            app.ExpStatusLampLabel.Text = 'Exp Status';
            app.ExpStatusLamp = uilamp(app.SessionControlsPanel);
            app.TrialInformationPanel = uipanel(app.UIFigure);
            app.TrialInformationPanel.Title = 'Trial Information';
            app.TrialLabel = uilabel(app.TrialInformationPanel);
            app.TrialLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.TrialInformationPanel, 'numeric');
            app.OrientationEditFieldLabel = uilabel(app.TrialInformationPanel);
            app.OrientationEditFieldLabel.Text = 'Orientation';
            app.OrientationEditField = uieditfield(app.TrialInformationPanel, 'numeric');
            app.DigitEditFieldLabel = uilabel(app.TrialInformationPanel);
            app.DigitEditFieldLabel.Text = 'Digit';
            app.DigitEditField = uieditfield(app.TrialInformationPanel, 'numeric');
            app.CurrentOrientationsPanel = uipanel(app.TrialInformationPanel);
            app.CurrentOrientationsPanel.Title = 'Current Orientations';
            app.D2EditFieldLabel = uilabel(app.CurrentOrientationsPanel);
            app.D2EditFieldLabel.Text = 'D2';
            app.D2EditField = uieditfield(app.CurrentOrientationsPanel, 'numeric');
            app.D3EditFieldLabel = uilabel(app.CurrentOrientationsPanel);
            app.D3EditFieldLabel.Text = 'D3';
            app.D3EditField = uieditfield(app.CurrentOrientationsPanel, 'numeric');
            app.D4EditFieldLabel = uilabel(app.CurrentOrientationsPanel);
            app.D4EditFieldLabel.Text = 'D4';
            app.D4EditField = uieditfield(app.CurrentOrientationsPanel, 'numeric');
            app.D5EditFieldLabel = uilabel(app.CurrentOrientationsPanel);
            app.D5EditFieldLabel.Text = 'D5';
            app.D5EditField = uieditfield(app.CurrentOrientationsPanel, 'numeric');
            app.ShapeEditFieldLabel = uilabel(app.TrialInformationPanel);
            app.ShapeEditFieldLabel.Text = 'Shape';
            app.ShapeEditField = uieditfield(app.TrialInformationPanel, 'text');
            app.Protocol2Label = uilabel(app.TrialInformationPanel);
            app.Protocol2Label.Text = 'Protocol 2';
            app.Protocol1Label = uilabel(app.TrialInformationPanel);
            app.Protocol1Label.Text = 'Protocol 1';
            app.ConnecttoDAQButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoDAQButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoDAQButtonPushed, true);
            app.ConnecttoDAQButton.Text = 'Connect to DAQ';
            app.DAQLampLabel = uilabel(app.UIFigure);
            app.DAQLampLabel.Text = 'DAQ';
            app.DAQLamp = uilamp(app.UIFigure);
            app.GenerateTrialsButton = uibutton(app.UIFigure, 'push');
            app.GenerateTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrialsButtonPushed, true);
            app.GenerateTrialsButton.Text = '4. Generate Trials';
            app.ALYSSA5744409825MANNY7182191692Label = uilabel(app.UIFigure);
            app.ALYSSA5744409825MANNY7182191692Label.Text = {'ALYSSA'; '(574)440-9825'; ''; 'MANNY'; '(718)219-1692'};
            app.TestStimulusPanel = uipanel(app.UIFigure);
            app.TestStimulusPanel.Title = 'Test Stimulus';
            app.DigitDropDownLabel = uilabel(app.TestStimulusPanel);
            app.DigitDropDownLabel.Text = 'Digit';
            app.DigitDropDown = uidropdown(app.TestStimulusPanel);
            app.DigitDropDown.Items = {'2', '3', '4', '5'};
            app.DigitDropDown.Value = '2';
            app.OrientationDropDownLabel = uilabel(app.TestStimulusPanel);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.TestStimulusPanel);
            app.OrientationDropDown.Items = {'0', '22.5', '36', '45', '60', '67.5', '72', '90', '108', '112.5', '120', '135', '144', '157.5'};
            app.OrientationDropDown.Value = '0';
            app.ProvideStimulusButton = uibutton(app.TestStimulusPanel, 'push');
            app.ProvideStimulusButton.ButtonPushedFcn = createCallbackFcn(app, @ProvideStimulusButtonPushed, true);
            app.ProvideStimulusButton.Text = 'Provide Stimulus';
            app.SessionNameEditFieldLabel = uilabel(app.UIFigure);
            app.SessionNameEditFieldLabel.Text = 'Session Name';
            app.SessionNameEditField = uieditfield(app.UIFigure, 'text');
            app.TestTriggerPanel = uipanel(app.UIFigure);
            app.TestTriggerPanel.Title = 'Test Trigger';
            app.TriggerLampLabel = uilabel(app.TestTriggerPanel);
            app.TriggerLampLabel.Text = 'Trigger';
            app.TriggerLamp = uilamp(app.TestTriggerPanel);
            app.TestTriggerButton = uibutton(app.TestTriggerPanel, 'push');
            app.TestTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @TestTriggerButtonPushed, true);
            app.TestTriggerButton.Text = 'Test Trigger';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainOrientationGUI_A002

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end