classdef stimControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TestTrialButton             matlab.ui.control.Button
        SpeedMotorControlPanel      matlab.ui.container.Panel
        StimDelaySpinner            matlab.ui.control.Spinner
        StimDelaySpinnerLabel       matlab.ui.control.Label
        STOPButton                  matlab.ui.control.Button
        TestSpeedButton             matlab.ui.control.Button
        DurationsecSpinner          matlab.ui.control.Spinner
        DurationsecSpinnerLabel     matlab.ui.control.Label
        SpeedSpinner                matlab.ui.control.Spinner
        SpeedSpinnerLabel           matlab.ui.control.Label
        StepperMotorControlPanel    matlab.ui.container.Panel
        Set90onFingerButton         matlab.ui.control.Button
        RotateCWButton              matlab.ui.control.Button
        RotateCCWButton             matlab.ui.control.Button
        StepSizeSpinner             matlab.ui.control.Spinner
        StepSizeSpinnerLabel        matlab.ui.control.Label
        RotationKnob                matlab.ui.control.Knob
        RotationKnobLabel           matlab.ui.control.Label
        MegaLamp                    matlab.ui.control.Lamp
        MegaLampLabel               matlab.ui.control.Label
        LeoLamp                     matlab.ui.control.Lamp
        LeoLampLabel                matlab.ui.control.Label
        LinearActuatorControlPanel  matlab.ui.container.Panel
        BaseAmountSpinner           matlab.ui.control.Spinner
        BaseAmountSpinnerLabel      matlab.ui.control.Label
        DepthAmountSpinner          matlab.ui.control.Spinner
        DepthAmountSpinnerLabel     matlab.ui.control.Label
        ToDepthButton               matlab.ui.control.Button
        ToBaseButton                matlab.ui.control.Button
        SetBaseButton               matlab.ui.control.Button
        MoveUpButton                matlab.ui.control.Button
        MoveDownButton              matlab.ui.control.Button
        MoveStepSpinner             matlab.ui.control.Spinner
        MoveStepSpinnerLabel        matlab.ui.control.Label
        ToHomeButton                matlab.ui.control.Button
        DepthSlider                 matlab.ui.control.Slider
        DepthSliderLabel            matlab.ui.control.Label
        MotionStimulusLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        serialLA; % serial connection to linear actuator (LEO Board)
        serialSM; % serial connection to stepper motor / herkulex (MEGA Board) 

        leoPort = 'COM9';
        megaPort = 'COM17'; 

        % Linear Actuator
        currentPosition = 900;
        BasePosition;
        DepthPosition;
        depthCommand = 1; 
        trialDepthCommand = 2; 

        % Rotating Stepper Motor
        currentAngle = 0; 
        rotateCommand = 6; 

        % Herkulex
        speedDurationCommand = 3; % speed with duration
        speedNoDurationCommand = 4; % speed without duration

    end
    
    methods (Access = private)
        
        function sendMtrCommand(app,commandLabel, moveAmount)
            if commandLabel == app.depthCommand % move linear actuator 
                serialCommand = sprintf("%d\n", [commandLabel moveAmount]); 
                write(app.serialLA, serialCommand, "string"); 

            elseif commandLabel == app.trialDepthCommand % move linear actuator for a trial 
                serialCommand = sprintf("%d\n", [commandLabel app.BasePosition app.DepthPosition app.DurationsecSpinner.Value*1000]);
                write(app.serialLA, serialCommand, "string"); 

            elseif commandLabel == 8 % move linear actuator for a trial 
                serialCommand = sprintf("%d\n", [commandLabel app.currentPosition moveAmount]);
                write(app.serialLA, serialCommand, "string"); 

            elseif commandLabel == app.rotateCommand % rotate stepper motor 
                if moveAmount <= 0
                    direction = 1; % move CCW 
                else 
                    direction = 0; % move CW
                end
                mtrNum = 1; 
                steps = round(6400 * abs(moveAmount) / 360);
                if steps < 1
                    return
                end
                serialCommand = sprintf("%d\n%d\n%d\n%d\n", [commandLabel mtrNum direction steps]); 
                write(app.serialSM, serialCommand, "string");

            elseif commandLabel == app.speedDurationCommand % speed motor - with duration 
                duration = app.DurationsecSpinner.Value * 1000;
                setSpeed = moveAmount; 
                serialCommand = sprintf("%d\n%d\n%d\n", [commandLabel setSpeed duration]); 
                write(app.serialSM, serialCommand, "string");  

            elseif commandLabel == app.speedNoDurationCommand % speed motor - without duration
                setSpeed = moveAmount;
                serialCommand = sprintf("%d\n%d\n", [commandLabel setSpeed]); 
                write(app.serialSM, serialCommand, "string"); 
            end
        end

        function moveAngle = calcMoveAngle(app, angle)
        currentAngle = app.currentAngle; 
            
            if currentAngle >= 180
                currentAngle = abs(currentAngle - 180);
            end
            goalAngle = angle;
            app.currentAngle = goalAngle; 
            if goalAngle >= 180
                goalAngle = abs(goalAngle - 180);
            end
            moveAngle = goalAngle - currentAngle; 
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Connect to LEO (Linear Actuator)
            try
                app.serialLA = serialport(app.leoPort, 9600);
                configureTerminator(app.serialLA, "CR/LF");
                set(app.serialLA, 'Timeout',15); 
                app.sendMtrCommand(app.depthCommand, app.currentPosition); 
                app.LeoLamp.Color = [0 1 0];
            catch
                app.LeoLamp.Color = [1 0 0];
            end

            % Connect to MEGA (Stepper Motor / Herkulex)
            try
                app.serialSM = serialport(app.megaPort, 115200);
                configureTerminator(app.serialSM, "CR/LF");
                set(app.serialSM, 'Timeout',15); 
                app.MegaLamp.Color = [0 1 0];
            catch
                app.MegaLamp.Color = [1 0 0];
            end
            
        end

        % Value changed function: DepthSlider
        function DepthSliderValueChanged(app, event)
            value = app.DepthSlider.Value;
%             app.currentPosition = value; 
            app.sendMtrCommand(8,value); 
            app.currentPosition = value; 
        end

        % Button pushed function: SetBaseButton
        function SetBaseButtonPushed(app, event)
            app.BasePosition = app.DepthSlider.Value - app.BaseAmountSpinner.Value*46;
            app.DepthPosition = app.BasePosition + (app.BaseAmountSpinner.Value + app.DepthAmountSpinner.Value)*46; 
        end

        % Button pushed function: ToBaseButton
        function ToBaseButtonPushed(app, event)
            app.currentPosition = app.BasePosition;
            app.DepthSlider.Value = app.BasePosition; 
            app.sendMtrCommand(app.depthCommand, app.BasePosition);
        end

        % Button pushed function: ToDepthButton
        function ToDepthButtonPushed(app, event)
            app.currentPosition = app.DepthPosition; 
            app.DepthSlider.Value = app.DepthPosition; 
            app.sendMtrCommand(app.depthCommand, app.DepthPosition); 
        end

        % Button pushed function: ToHomeButton
        function ToHomeButtonPushed(app, event)
            app.DepthSlider.Value = 900; 
            app.sendMtrCommand(app.depthCommand, 900);            
        end

        % Button pushed function: Set90onFingerButton
        function Set90onFingerButtonPushed(app, event)
            app.currentAngle = 90; 
            app.RotationKnob.Value = 90; 
        end

        % Button pushed function: RotateCCWButton
        function RotateCCWButtonPushed(app, event)
            angle = app.StepSizeSpinner.Value;
            app.sendMtrCommand(app.rotateCommand, angle); 
        end

        % Button pushed function: RotateCWButton
        function RotateCWButtonPushed(app, event)
            angle = app.StepSizeSpinner.Value;
            app.sendMtrCommand(app.rotateCommand, -angle); 
        end

        % Value changed function: RotationKnob
        function RotationKnobValueChanged(app, event)
            targetAngle = app.RotationKnob.Value;
            moveAngle = app.calcMoveAngle(targetAngle); 
            % if no movement needed, exit
            if moveAngle == 0
                return;
            end
            app.sendMtrCommand(app.rotateCommand, moveAngle); 
        end

        % Button pushed function: TestSpeedButton
        function TestSpeedButtonPushed(app, event)
            setSpeed = app.SpeedSpinner.Value;
            if app.currentAngle >= 180
                direction = 1;
            else
                direction = -1; 
            end
            app.sendMtrCommand(app.speedNoDurationCommand, setSpeed * direction); 
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            setSpeed = 0;
            app.sendMtrCommand(app.speedNoDurationCommand, setSpeed); 
        end

        % Button pushed function: TestTrialButton
        function TestTrialButtonPushed(app, event)
            % Start Rotating Herkulex
            setSpeed = app.SpeedSpinner.Value;
            if app.currentAngle >= 180
                direction = 1;
            else
                direction = -1; 
            end
            app.sendMtrCommand(app.speedNoDurationCommand, setSpeed * direction); 
            
            % delay between starting wheel rotation and providing stimulus
            t = tic;
            while toc(t) < app.StimDelaySpinner.Value
                drawnow('limitrate'); % Allows UI to stay responsive
            end
%             pause(app.StimDelaySpinner.Value); 

            % Move Linear Actuator For Trial
            app.sendMtrCommand(app.trialDepthCommand, 0); 
            
            % delay between raising up stimulus and stopping wheel rotation
            % (added 0.5 seconds for extra buffer) 
            t = tic;
            while toc(t) < app.StimDelaySpinner.Value + 0.5
                drawnow('limitrate'); % Allows UI to stay responsive
            end

%             pause(app.StimDelaySpinner.Value + 0.5); 

            % Stop Herkulex 
            app.sendMtrCommand(app.speedNoDurationCommand, 0); 

        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            moveAmount = round(app.MoveStepSpinner.Value * 46);
%             app.DepthSlider.Value = app.currentPosition; 
            app.sendMtrCommand(8, app.currentPosition + moveAmount); 
            app.currentPosition = app.currentPosition + moveAmount;
            app.DepthSlider.Value = app.currentPosition; 
%             app.sendMtrCommand(app.depthCommand, app.currentPosition); 
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            moveAmount = round(app.MoveStepSpinner.Value * 46); 
%             app.DepthSlider.Value = app.currentPosition;
            app.sendMtrCommand(app.depthCommand, app.currentPosition - moveAmount); 
            app.currentPosition = app.currentPosition - moveAmount;
            app.DepthSlider.Value = app.currentPosition;
%             app.sendMtrCommand(app.depthCommand, app.currentPosition); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotionStimulusLabel = uilabel(app.UIFigure);
            app.MotionStimulusLabel.Text = 'Motion Stimulus ';
            app.LinearActuatorControlPanel = uipanel(app.UIFigure);
            app.LinearActuatorControlPanel.Title = 'Linear Actuator Control';
            app.DepthSliderLabel = uilabel(app.LinearActuatorControlPanel);
            app.DepthSliderLabel.Text = 'Depth ';
            app.DepthSlider = uislider(app.LinearActuatorControlPanel);
            app.DepthSlider.Limits = [900 2100];
            app.DepthSlider.ValueChangedFcn = createCallbackFcn(app, @DepthSliderValueChanged, true);
            app.DepthSlider.Value = 900;
            app.ToHomeButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.ToHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ToHomeButtonPushed, true);
            app.ToHomeButton.Text = 'To Home';
            app.MoveStepSpinnerLabel = uilabel(app.LinearActuatorControlPanel);
            app.MoveStepSpinnerLabel.Text = 'Move Step';
            app.MoveStepSpinner = uispinner(app.LinearActuatorControlPanel);
            app.MoveStepSpinner.Step = 0.25;
            app.MoveStepSpinner.Limits = [0 10];
            app.MoveDownButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.MoveUpButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.SetBaseButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.SetBaseButton.ButtonPushedFcn = createCallbackFcn(app, @SetBaseButtonPushed, true);
            app.SetBaseButton.Text = 'Set Base';
            app.ToBaseButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.ToBaseButton.ButtonPushedFcn = createCallbackFcn(app, @ToBaseButtonPushed, true);
            app.ToBaseButton.Text = 'To Base';
            app.ToDepthButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.ToDepthButton.ButtonPushedFcn = createCallbackFcn(app, @ToDepthButtonPushed, true);
            app.ToDepthButton.Text = 'To Depth';
            app.DepthAmountSpinnerLabel = uilabel(app.LinearActuatorControlPanel);
            app.DepthAmountSpinnerLabel.Text = 'Depth Amount';
            app.DepthAmountSpinner = uispinner(app.LinearActuatorControlPanel);
            app.DepthAmountSpinner.Step = 0.25;
            app.DepthAmountSpinner.Limits = [0 2];
            app.DepthAmountSpinner.Value = 1;
            app.BaseAmountSpinnerLabel = uilabel(app.LinearActuatorControlPanel);
            app.BaseAmountSpinnerLabel.Text = 'Base Amount';
            app.BaseAmountSpinner = uispinner(app.LinearActuatorControlPanel);
            app.BaseAmountSpinner.Step = 0.5;
            app.BaseAmountSpinner.Limits = [0 5];
            app.BaseAmountSpinner.Value = 1.5;
            app.LeoLampLabel = uilabel(app.UIFigure);
            app.LeoLampLabel.Text = 'Leo';
            app.LeoLamp = uilamp(app.UIFigure);
            app.MegaLampLabel = uilabel(app.UIFigure);
            app.MegaLampLabel.Text = 'Mega';
            app.MegaLamp = uilamp(app.UIFigure);
            app.StepperMotorControlPanel = uipanel(app.UIFigure);
            app.StepperMotorControlPanel.Title = 'Stepper Motor Control ';
            app.RotationKnobLabel = uilabel(app.StepperMotorControlPanel);
            app.RotationKnobLabel.Text = 'Rotation';
            app.RotationKnob = uiknob(app.StepperMotorControlPanel, 'continuous');
            app.RotationKnob.Limits = [0 360];
            app.RotationKnob.ValueChangedFcn = createCallbackFcn(app, @RotationKnobValueChanged, true);
            app.StepSizeSpinnerLabel = uilabel(app.StepperMotorControlPanel);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.StepSizeSpinner = uispinner(app.StepperMotorControlPanel);
            app.StepSizeSpinner.Step = 0.225;
            app.StepSizeSpinner.Limits = [0 360];
            app.RotateCCWButton = uibutton(app.StepperMotorControlPanel, 'push');
            app.RotateCCWButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCCWButtonPushed, true);
            app.RotateCCWButton.Text = 'Rotate CCW';
            app.RotateCWButton = uibutton(app.StepperMotorControlPanel, 'push');
            app.RotateCWButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCWButtonPushed, true);
            app.RotateCWButton.Text = 'Rotate CW';
            app.Set90onFingerButton = uibutton(app.StepperMotorControlPanel, 'push');
            app.Set90onFingerButton.ButtonPushedFcn = createCallbackFcn(app, @Set90onFingerButtonPushed, true);
            app.Set90onFingerButton.Text = 'Set 90 on Finger';
            app.SpeedMotorControlPanel = uipanel(app.UIFigure);
            app.SpeedMotorControlPanel.Title = 'Speed Motor Control ';
            app.SpeedSpinnerLabel = uilabel(app.SpeedMotorControlPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.SpeedMotorControlPanel);
            app.SpeedSpinner.Step = 10;
            app.SpeedSpinner.Limits = [0 1023];
            app.SpeedSpinner.Value = 510;
            app.DurationsecSpinnerLabel = uilabel(app.SpeedMotorControlPanel);
            app.DurationsecSpinnerLabel.Text = 'Duration (sec)';
            app.DurationsecSpinner = uispinner(app.SpeedMotorControlPanel);
            app.DurationsecSpinner.Step = 0.25;
            app.DurationsecSpinner.Limits = [0 10];
            app.DurationsecSpinner.Value = 1;
            app.TestSpeedButton = uibutton(app.SpeedMotorControlPanel, 'push');
            app.TestSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @TestSpeedButtonPushed, true);
            app.TestSpeedButton.Text = 'Test Speed';
            app.STOPButton = uibutton(app.SpeedMotorControlPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.StimDelaySpinnerLabel = uilabel(app.SpeedMotorControlPanel);
            app.StimDelaySpinnerLabel.Text = 'StimDelay';
            app.StimDelaySpinner = uispinner(app.SpeedMotorControlPanel);
            app.StimDelaySpinner.Step = 0.25;
            app.StimDelaySpinner.Limits = [0 10];
            app.StimDelaySpinner.Value = 1;
            app.TestTrialButton = uibutton(app.UIFigure, 'push');
            app.TestTrialButton.ButtonPushedFcn = createCallbackFcn(app, @TestTrialButtonPushed, true);
            app.TestTrialButton.Text = 'Test Trial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end